classdef gui < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        SceneButton                 matlab.ui.control.Button
        BackgroundButton            matlab.ui.control.Button
        RunButton                   matlab.ui.control.Button
        RenderingModeDropDownLabel  matlab.ui.control.Label
        RenderingModeDropDown       matlab.ui.control.DropDown
        StartPointEditFieldLabel    matlab.ui.control.Label
        StartPointEditField         matlab.ui.control.NumericEditField
        StorePathButton             matlab.ui.control.Button
        Scene                       matlab.ui.control.Label
        Background                  matlab.ui.control.Label
        Store_Path                  matlab.ui.control.Label
        Image                       matlab.ui.control.Image
        Image2                      matlab.ui.control.Image
        LeftImageButtonGroup        matlab.ui.container.ButtonGroup
        Sequence1Button             matlab.ui.control.ToggleButton
        Sequence2Button_left        matlab.ui.control.ToggleButton
        RightImageButtonGroup       matlab.ui.container.ButtonGroup
        Sequence2Button_right       matlab.ui.control.ToggleButton
        Sequence3Button             matlab.ui.control.ToggleButton
        StoreVideoCheckBox          matlab.ui.control.CheckBox
        ShowResultButton            matlab.ui.control.Button
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            assignin('base', 'L', 1);
            assignin('base', 'R', 2);
            assignin('base', 'start', 1);
            assignin('base', 'mode', 'foreground');
            assignin('base', 'dst', 'output.avi');
            assignin('base', 'store', 0);
            assignin('base', 'N', 5); % N can onbly be set as a default
        end

        % Button pushed function: SceneButton
        function SceneButtonPushed(app, event)
            
            % open path selection dialog box
            scene_path = uigetdir(matlabroot);
            
            % only if the selection hasn't been canceled
            if scene_path ~= 0
                % store scene_path to workspace variables
                % scene path: src
                assignin('base', 'src', scene_path)
                
                % Update Label
                text = "Path: " + scene_path;
                app.Scene.Text = text;
                drawnow
            end
        end

        % Button pushed function: BackgroundButton
        function BackgroundButtonPushed(app, event)
            % open path selection dialog box
            background_path = uigetfile({'*.jpg';'*.png';'*.PNG';'*.PNG-Bild'},'File Selector');
            
            % only if the selection hasn't been canceled
            if background_path ~= 0
                % store scene_path to workspace variables
                % background path: background_path
                assignin('base', 'background_path', background_path)
                
                % Update Label
                text = "Path: " + background_path;
                app.Background.Text = text;
                drawnow
            end
        end

        % Value changed function: RenderingModeDropDown
        function RenderingModeDropDownValueChanged(app, event)
            value = app.RenderingModeDropDown.Value;
            % store rendering mdoe to workspace variables
            % rendering mdoe: mode
            assignin('base', 'mode', value)
        end

        % Value changed function: StartPointEditField
        function StartPointEditFieldValueChanged(app, event)
            value = app.StartPointEditField.Value;
            % store start to workspace variables
            % start: start
            assignin('base', 'start', value)
        end

        % Button pushed function: StorePathButton
        function StorePathButtonPushed(app, event)
            
            % open path selection dialog box
            store_path = uigetdir(matlabroot);
            
            % only if the selection hasn't been canceled
            if store_path ~= 0
                % store scene_path to workspace variables
                % store path: dst
                %assignin('base', 'dst', store_path)
                
                % Update Label
                %text = "Path: " + store_path;
                %app.Store_Path.Text = text;
                %drawnow
            end
        end

        % Button pushed function: RunButton
        function RunButtonPushed(app, event)
            src = evalin('base','src');
            background_path = evalin('base','background_path');
            dst = evalin('base','dst');
            L = evalin('base','L');
            R = evalin('base','R');
            mode = evalin('base','mode');
            N = evalin('base','N');
            start = evalin('base','start');
            store = evalin('base','store');
            %output.avi = evalin('base', 'output.avi');
            
            
            status = 0;
            %check_workspace;
            if status == 0
                app.ShowResultButton.Visible = 'off';
                
                gui_config;
                challenge;
                
                %show run button
                app.ShowResultButton.Visible = 'on';
                %assignin('base', 'result', result);
            else
                errordlg('Parameters missing','Error')
            end
            
        end

        % Selection changed function: LeftImageButtonGroup
        function LeftImageButtonGroupSelectionChanged(app, event)
            selectedButton = app.LeftImageButtonGroup.SelectedObject;
            
            % Assign selected Button to Sequence number
            if selectedButton.Tag == "sq1"
                L = 1;
            else
                L = 2;
            end
            
            % Store selected Button to Workspace
            assignin('base', 'L', L)
            
            % Change selection of Right image if left image is image 2
            if selectedButton.Tag == "sq2"
                app.RightImageButtonGroup.SelectedObject = app.Sequence3Button; % Set Button UI
                assignin('base', 'R', 3) % Write to Workspace
                drawnow % refresh UI
            end
            
        end

        % Selection changed function: RightImageButtonGroup
        function RightImageButtonGroupSelectionChanged(app, event)
            selectedButton = app.RightImageButtonGroup.SelectedObject;
            
            % Assign selected Button to Sequence number
            if selectedButton.Tag == "sq2"
                R = 2;
            else
                R = 3;
            end
            
            % Store selected Button to Workspace
            assignin('base', 'R', R);
            
            % Change selection of Right image if left image is image 2
            if selectedButton.Tag == "sq2"
                app.LeftImageButtonGroup.SelectedObject = app.Sequence1Button; % Set Button UI
                assignin('base', 'L', 1) % Write to Workspace
                drawnow % refresh UI
            end
            
        end

        % Value changed function: StoreVideoCheckBox
        function StoreVideoCheckBoxValueChanged(app, event)
            value = app.StoreVideoCheckBox.Value;
            
            assignin('base', 'store', 1) % Write to Workspace
        end

        % Button pushed function: ShowResultButton
        function ShowResultButtonPushed(app, event)
            % set frame rate
            frames_s = 25;
            del = 1/frames_s;
            
            videoReader = VideoReader('output.avi');
            videoPlayer = vision.VideoPlayer;
            
            while hasFrame(videoReader)
                frame = readFrame(videoReader);
                step(videoPlayer,frame);
                
                pause(del);
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.SceneButton = uibutton(app.UIFigure, 'push');
            app.SceneButton.ButtonPushedFcn = createCallbackFcn(app, @SceneButtonPushed, true);
            app.SceneButton.Text = 'Scene';
            app.BackgroundButton = uibutton(app.UIFigure, 'push');
            app.BackgroundButton.ButtonPushedFcn = createCallbackFcn(app, @BackgroundButtonPushed, true);
            app.BackgroundButton.Text = 'Background';
            app.RunButton = uibutton(app.UIFigure, 'push');
            app.RunButton.ButtonPushedFcn = createCallbackFcn(app, @RunButtonPushed, true);
            app.RunButton.Text = 'Run';
            app.RenderingModeDropDownLabel = uilabel(app.UIFigure);
            app.RenderingModeDropDownLabel.Text = 'Rendering Mode';
            app.RenderingModeDropDown = uidropdown(app.UIFigure);
            app.RenderingModeDropDown.Items = {'foreground', 'background', 'overlay', 'substitute'};
            app.RenderingModeDropDown.ValueChangedFcn = createCallbackFcn(app, @RenderingModeDropDownValueChanged, true);
            app.RenderingModeDropDown.Value = 'foreground';
            app.StartPointEditFieldLabel = uilabel(app.UIFigure);
            app.StartPointEditFieldLabel.Text = 'Start Point';
            app.StartPointEditField = uieditfield(app.UIFigure, 'numeric');
            app.StartPointEditField.Limits = [1 Inf];
            app.StartPointEditField.ValueDisplayFormat = '%.0f';
            app.StartPointEditField.ValueChangedFcn = createCallbackFcn(app, @StartPointEditFieldValueChanged, true);
            app.StartPointEditField.Value = 1;
            app.StorePathButton = uibutton(app.UIFigure, 'push');
            app.StorePathButton.ButtonPushedFcn = createCallbackFcn(app, @StorePathButtonPushed, true);
            app.StorePathButton.Visible = 'off';
            app.StorePathButton.Text = 'Store Path';
            app.Scene = uilabel(app.UIFigure);
            app.Scene.Text = 'Not selected yet';
            app.Background = uilabel(app.UIFigure);
            app.Background.Text = 'Not selected yet';
            app.Store_Path = uilabel(app.UIFigure);
            app.Store_Path.Visible = 'off';
            app.Store_Path.Text = 'Not selected yet';
            app.Image = uiimage(app.UIFigure);
            app.Image2 = uiimage(app.UIFigure);
            app.LeftImageButtonGroup = uibuttongroup(app.UIFigure);
            app.LeftImageButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @LeftImageButtonGroupSelectionChanged, true);
            app.LeftImageButtonGroup.Title = 'Left Image';
            app.Sequence1Button = uitogglebutton(app.LeftImageButtonGroup);
            app.Sequence1Button.Tag = 'sq1';
            app.Sequence1Button.Text = 'Sequence 1';
            app.Sequence1Button.Value = true;
            app.Sequence2Button_left = uitogglebutton(app.LeftImageButtonGroup);
            app.Sequence2Button_left.Tag = 'sq2';
            app.Sequence2Button_left.Text = 'Sequence 2';
            app.RightImageButtonGroup = uibuttongroup(app.UIFigure);
            app.RightImageButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @RightImageButtonGroupSelectionChanged, true);
            app.RightImageButtonGroup.Title = 'Right Image';
            app.Sequence2Button_right = uitogglebutton(app.RightImageButtonGroup);
            app.Sequence2Button_right.Tag = 'sq2';
            app.Sequence2Button_right.Text = 'Sequence 2';
            app.Sequence2Button_right.Value = true;
            app.Sequence3Button = uitogglebutton(app.RightImageButtonGroup);
            app.Sequence3Button.Tag = 'sq3';
            app.Sequence3Button.Text = 'Sequence 3';
            app.StoreVideoCheckBox = uicheckbox(app.UIFigure);
            app.StoreVideoCheckBox.ValueChangedFcn = createCallbackFcn(app, @StoreVideoCheckBoxValueChanged, true);
            app.StoreVideoCheckBox.Text = 'Store Video';
            app.ShowResultButton = uibutton(app.UIFigure, 'push');
            app.ShowResultButton.ButtonPushedFcn = createCallbackFcn(app, @ShowResultButtonPushed, true);
            app.ShowResultButton.Visible = 'off';
            app.ShowResultButton.Text = 'Show Result';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = gui

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end